classdef CovidMeter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CountryListBoxLabel       matlab.ui.control.Label
        CountryListBox            matlab.ui.control.ListBox
        StateListBoxLabel         matlab.ui.control.Label
        StateListBox              matlab.ui.control.ListBox
        OptionButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton          matlab.ui.control.RadioButton
        DailyButton               matlab.ui.control.RadioButton
        UIAxes                    matlab.ui.control.UIAxes
        AvergeofDaystoPlotLabel   matlab.ui.control.Label
        AvergeofDaystoPlotSlider  matlab.ui.control.Slider
        DataButtonGroup           matlab.ui.container.ButtonGroup
        BothButton                matlab.ui.control.RadioButton
        CasesButton               matlab.ui.control.RadioButton
        DeathButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        dates
        countries
        state
        
        CumCas
        CumDet
        DailyCas
        DailyDet
                
        cases
        cumulative
        xdays
        
        countriesSelected
        stateSelected
        
        count
    end
%     methods (Access = public)
%         function RefreshPlot(app)
%         end
%     end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % LOAD DATA
            load Cov_data.mat covid_data;
            app.dates =  (covid_data(1,3:end)); %row of dates
            
            app.countries = covid_data(2:end,1); %colum of countries 331  x    1
            app.state = covid_data(2:end,2); %colum of states 331  x    1
                for i = 1:1:length(app.countries)
                    if isequal(app.state{i},'')
                        app.state{i} = 'All';
                    end
                end
            
            covid_data = cell2mat(covid_data(2:end,3:end));
            app.CumCas = covid_data(:,1:2:end);
            app.CumDet = covid_data(:,2:2:end);
            clear covid_data;
            app.DailyCas = app.CumCas;
            app.DailyDet = app.CumDet;
                for i = length(app.dates):-1:2
                app.DailyCas(:,i) = app.DailyCas(:,i)-app.DailyCas(:,i-1);
                app.DailyDet(:,i) = app.DailyDet(:,i)-app.DailyDet(:,i-1);
                end
                app.DailyCas(:,1) = 0; app.DailyDet(:,1) = 0;
                
                
%           INITIALISE Parameter
            app.count = 0;
            app.xdays = 1;
            app.cases = 'Both';
            app.cumulative = 'Cumulative';
            app.countriesSelected = app.countries{1};
            app.stateSelected = app.state{1};

%           Country and State
            app.CountryListBox.Items = app.countries(1);
            for i = 2:1:length(app.countries')
                if ~isequal(app.countries{i},app.CountryListBox.Items{end})
                    app.CountryListBox.Items{end+1} = app.countries{i};
                end
            end
            
            app.StateListBox.Items={};
            for i = 1:1:length(app.countries')
                if isequal(app.countries{i},app.countriesSelected)
                    app.StateListBox.Items{end+1} = app.state{i};
                end
            end
            RefreshPlot;
%             
%         Y = app.CumCas(data_row,:);
%         yyaxis(app.UIAxes,'left');
%         bar(app.UIAxes,datetime(app.dates),movmean(Y,app.xdays));
%         Y = app.CumDet(data_row,:);
%         yyaxis(app.UIAxes,'right');
%         plot(app.UIAxes,datetime(app.dates),movmean(Y,app.xdays),'color','r');

        end

        % Value changed function: AvergeofDaystoPlotSlider
        function AvergeofDaystoPlotSliderValueChanged(app, event)
            value = app.AvergeofDaystoPlotSlider.Value;
            app.AvergeofDaystoPlotSlider.Value = round(value);
            app.xdays = round(value);
            RefreshPlot;
           
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            app.cumulative = selectedButton.Text;
            RefreshPlot;
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            value = app.StateListBox.Value;
            app.stateSelected = value;
            RefreshPlot;
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            app.countriesSelected = value;
            
            app.StateListBox.Items={};
            for i = 1:1:length(app.countries')
                if isequal(app.countries{i},app.countriesSelected)
                    app.StateListBox.Items{end+1} = app.state{i};
                end
            end
            app.stateSelected = app.StateListBox.Items{1};
            
            RefreshPlot;
        end

        % Selection changed function: DataButtonGroup
        function DataButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataButtonGroup.SelectedObject;
            app.cases = selectedButton.Text;
            RefreshPlot;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            com.mathworks.mde.cmdwin.CmdWinMLIF.getInstance().processKeyFromC(2,67,'C')
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateListBoxLabel = uilabel(app.UIFigure);
            app.StateListBoxLabel.Text = 'State';
            app.StateListBox = uilistbox(app.UIFigure);
            app.StateListBox.Items = {};
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.StateListBox.Value = {};
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Y ')
            app.AvergeofDaystoPlotLabel = uilabel(app.UIFigure);
            app.AvergeofDaystoPlotLabel.Text = {'Averge # of '; 'Days to Plot'; ''};
            app.AvergeofDaystoPlotSlider = uislider(app.UIFigure);
            app.AvergeofDaystoPlotSlider.Limits = [1 100];
            app.AvergeofDaystoPlotSlider.ValueChangedFcn = createCallbackFcn(app, @AvergeofDaystoPlotSliderValueChanged, true);
            app.AvergeofDaystoPlotSlider.Value = 1;
            app.DataButtonGroup = uibuttongroup(app.UIFigure);
            app.DataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataButtonGroupSelectionChanged, true);
            app.DataButtonGroup.Title = 'Data';
            app.BothButton = uiradiobutton(app.DataButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.CasesButton = uiradiobutton(app.DataButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathButton = uiradiobutton(app.DataButtonGroup);
            app.DeathButton.Text = 'Death';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidMeter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end